classdef doan_canhan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Hamhoitu_EditField      matlab.ui.control.EditField
        NhphmhitcahmsfxLabel    matlab.ui.control.Label
        Nhapsaiso_EditField     matlab.ui.control.EditField
        NhpsaischophpLabel      matlab.ui.control.Label
        Solanlap_Label          matlab.ui.control.Label
        Nghiem_Label            matlab.ui.control.Label
        SlnlpLabel              matlab.ui.control.Label
        NghimcahmsLabel         matlab.ui.control.Label
        Tinh_Button             matlab.ui.control.Button
        Phuongphap_ButtonGroup  matlab.ui.container.ButtonGroup
        Tieptuyen_Button        matlab.ui.control.RadioButton
        Lap_Button              matlab.ui.control.RadioButton
        Chiadoi_Button          matlab.ui.control.RadioButton
        B_EditField             matlab.ui.control.EditField
        NhpbEditFieldLabel      matlab.ui.control.Label
        A_EditField             matlab.ui.control.EditField
        NhpaEditFieldLabel      matlab.ui.control.Label
        KhongphnlynghimabLabel  matlab.ui.control.Label
        NhapHS_EditField        matlab.ui.control.EditField
        NhphmsfxEditFieldLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Tinh_Button
        function Tinh_ButtonPushed(app, event)
            f = app.NhapHS_EditField.Value;
            a = str2num(app.A_EditField.Value);
            b = str2num(app.B_EditField.Value);
            saiso = str2num(app.Nhapsaiso_EditField.Value);

            function result = change(fx)
                result = strrep(fx,'*','.*');
                result = strrep(result,'^','.^');
                result = strrep(result,'/','./');
            end

            if app.Chiadoi_Button.Value == 1
                [nghiemcd,solanlapcd] = chiadoi(f,a,b,saiso);
                app.Nghiem_Label.Text = num2str(nghiemcd);
                app.Solanlap_Label.Text = num2str(solanlapcd);
                ft = change(f);
                ft = str2func(['@(x)',ft]);
                x = linspace(a,b,100);
                y = ft(x);
                plot(app.UIAxes,x,y,x,x*0,"r");
                xlabel(app.UIAxes,'X');
                ylabel(app.UIAxes,'Y');
                title(app.UIAxes,'Đồ thị phương pháp chia đôi');
                legend(app.UIAxes,'y = f(x)','y = 0');
            end

            if app.Tieptuyen_Button.Value == 1
                [nghiemtt,solanlaptt] = tieptuyen(f,a,b,saiso);
                app.Nghiem_Label.Text = num2str(nghiemtt);
                app.Solanlap_Label.Text = num2str(solanlaptt);
                ft = change(f);
                ft = str2func(['@(x)',ft]);
                x = linspace(a,b,100);
                y = ft(x);
                plot(app.UIAxes,x,y,x,x*0,"r");
                xlabel(app.UIAxes,'X');
                ylabel(app.UIAxes,'Y');
                title(app.UIAxes,'Đồ thị phương pháp tiếp tuyến');
                legend(app.UIAxes,'y = f(x)','y = 0');
            end

            if app.Lap_Button.Value == 1
                fp = app.Hamhoitu_EditField.Value;
                [nghiemlap,solanlaplap] = lap(fp,a,b,saiso);
                app.Nghiem_Label.Text = num2str(nghiemlap);
                app.Solanlap_Label.Text = num2str(solanlaplap);
                ft = change(f);
                ft = str2func(['@(x)',ft]);
                x = linspace(a,b,100);
                y = ft(x);
                plot(app.UIAxes,x,y,x,x*0,"r");
                xlabel(app.UIAxes,'X');
                ylabel(app.UIAxes,'Y');
                title(app.UIAxes,'Đồ thị phương pháp lặp');
                legend(app.UIAxes,'y = f(x)','y = 0');
            end
        end

        % Selection changed function: Phuongphap_ButtonGroup
        function Phuongphap_ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Phuongphap_ButtonGroup.SelectedObject;
            if selectedButton == app.Lap_Button
                app.Hamhoitu_EditField.Visible = "on";
                app.NhapHS_EditField.Visible = "off";
            else
                app.Hamhoitu_EditField.Visible = "off";
                app.NhapHS_EditField.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhphmsfxEditFieldLabel = uilabel(app.UIFigure);
            app.NhphmsfxEditFieldLabel.Text = 'Nhập hàm số  f(x)';
            app.NhapHS_EditField = uieditfield(app.UIFigure, 'text');
            app.NhapHS_EditField.Value = 'x-sin(x)-0.25';
            app.KhongphnlynghimabLabel = uilabel(app.UIFigure);
            app.KhongphnlynghimabLabel.Text = 'Khoảng phân ly nghiệm [a;b] ';
            app.NhpaEditFieldLabel = uilabel(app.UIFigure);
            app.NhpaEditFieldLabel.Text = 'Nhập a:';
            app.A_EditField = uieditfield(app.UIFigure, 'text');
            app.A_EditField.Value = '-1';
            app.NhpbEditFieldLabel = uilabel(app.UIFigure);
            app.NhpbEditFieldLabel.Text = 'Nhập b:';
            app.B_EditField = uieditfield(app.UIFigure, 'text');
            app.B_EditField.Value = '2';
            app.Phuongphap_ButtonGroup = uibuttongroup(app.UIFigure);
            app.Phuongphap_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Phuongphap_ButtonGroupSelectionChanged, true);
            app.Phuongphap_ButtonGroup.Title = 'Chọn phương pháp ';
            app.Chiadoi_Button = uiradiobutton(app.Phuongphap_ButtonGroup);
            app.Chiadoi_Button.Text = 'Chia đôi';
            app.Chiadoi_Button.Value = true;
            app.Lap_Button = uiradiobutton(app.Phuongphap_ButtonGroup);
            app.Lap_Button.Text = 'Lặp ';
            app.Tieptuyen_Button = uiradiobutton(app.Phuongphap_ButtonGroup);
            app.Tieptuyen_Button.Text = 'Tiếp tuyến';
            app.Tinh_Button = uibutton(app.UIFigure, 'push');
            app.Tinh_Button.ButtonPushedFcn = createCallbackFcn(app, @Tinh_ButtonPushed, true);
            app.Tinh_Button.Text = 'TÍnh';
            app.NghimcahmsLabel = uilabel(app.UIFigure);
            app.NghimcahmsLabel.Text = 'Nghiệm của hàm số:';
            app.SlnlpLabel = uilabel(app.UIFigure);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.Nghiem_Label = uilabel(app.UIFigure);
            app.Nghiem_Label.Text = '0';
            app.Solanlap_Label = uilabel(app.UIFigure);
            app.Solanlap_Label.Text = '0';
            app.NhpsaischophpLabel = uilabel(app.UIFigure);
            app.NhpsaischophpLabel.Text = {'Nhập sai số '; 'cho phép'};
            app.Nhapsaiso_EditField = uieditfield(app.UIFigure, 'text');
            app.Nhapsaiso_EditField.Value = '0.005';
            app.NhphmhitcahmsfxLabel = uilabel(app.UIFigure);
            app.NhphmhitcahmsfxLabel.Text = {'Nhập hàm hội tụ '; 'của hàm số f(x)'};
            app.Hamhoitu_EditField = uieditfield(app.UIFigure, 'text');
            app.Hamhoitu_EditField.Value = 'sin(x)+0.25';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doan_canhan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end